classdef project3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EfficiencyLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        DirectivityLabel                matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        GainLabel                       matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Label3                          matlab.ui.control.Label
        ChooseAntennaTypeDropDown       matlab.ui.control.DropDown
        ChooseAntennaTypeDropDownLabel  matlab.ui.control.Label
        FrequencyUnitDropDown           matlab.ui.control.DropDown
        FrequencyUnitLabel              matlab.ui.control.Label
        LossResistanceLabel             matlab.ui.control.Label
        InputFrequencyLabel             matlab.ui.control.Label
        R1EditField                     matlab.ui.control.NumericEditField
        R1EditFieldLabel                matlab.ui.control.Label
        FEditField                      matlab.ui.control.NumericEditField
        FEditFieldLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Validate Frequency
            if app.FEditField.Value <= 0
              uialert(app.UIFigure,'Frequency must be greater than 0.' ...
                    ,'Input Error');
                return;
            end

            % Read Frequency
            f=app.FEditField.Value;

            switch app.FrequencyUnitDropDown.Value
                case 'Hz'
                    %NO Conversion
                    
                case 'kHz'
                    f = f * 1e3;
                case 'MHz'
                    f = f * 1e6;
                case 'GHz'
                    f = f * 1e9;
                otherwise
          uialert(app.UIFigure,'Select a valid frequency unit.','Error');
                    return;
            end

            % Constants
            c = 3e8;
            Rl = app.R1EditField.Value;
            lambda = c/f;

            % Create Antenna
            if strcmp(app.ChooseAntennaTypeDropDown.Value,'Dipole')

                L = lambda/2;
                W = lambda/200;

                ant = dipole('Length',L,'Width',W);

            else

                L = lambda/4;
                W = lambda/200;

                ant = monopole('Height',L,'Width',W);

            end

            % Radiation Pattern
            theta = 0:1:180;

            patternData = pattern(ant,f,0,theta,'Type','directivity');

            plot(app.UIAxes,theta,patternData,'LineWidth',2);
            xlim(app.UIAxes,[0 200]);
            ylim(app.UIAxes,[-50 10]);
            xticks(app.UIAxes,0:50:200);
            yticks(app.UIAxes,-50:10:10);

            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Elevation Angle (Degrees)');
            ylabel(app.UIAxes,'Directivity (dBi)');
            title(app.UIAxes,'Elevation Radiation Pattern');

            % Directivity
            D_dBi = pattern(ant,f,0,0);
            D = 10^(D_dBi/10);

            % Efficiency
            Zin = impedance(ant,f);
            Rr = real(Zin);
            eta = Rr/(Rr + Rl);

            % Gain
            G = eta * D;
            G_dBi = 10*log10(G);

            % Display Results
            app.GEditField.Value = round(G_dBi,2);
            app.DEditField.Value = round(D_dBi,2);
            app.nEditField.Value = round(eta*100,2);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Elevation Radiation Pattern')
            xlabel(app.UIAxes, 'Elevation Angle(Degrees)')
            ylabel(app.UIAxes, 'Directivity(dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.FEditFieldLabel = uilabel(app.UIFigure);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.InputFrequencyLabel = uilabel(app.UIFigure);
            app.InputFrequencyLabel.Text = 'Input Frequency';
            app.LossResistanceLabel = uilabel(app.UIFigure);
            app.LossResistanceLabel.Text = 'Loss Resistance';
            app.FrequencyUnitLabel = uilabel(app.UIFigure);
            app.FrequencyUnitLabel.Text = 'FrequencyUnit';
            app.FrequencyUnitDropDown = uidropdown(app.UIFigure);
            app.FrequencyUnitDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.FrequencyUnitDropDown.Value = 'Hz';
            app.ChooseAntennaTypeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseAntennaTypeDropDownLabel.Text = 'Choose Antenna Type';
            app.ChooseAntennaTypeDropDown = uidropdown(app.UIFigure);
            app.ChooseAntennaTypeDropDown.Items = {'Dipole', 'Monopole', 'Option 3', 'Option 4'};
            app.ChooseAntennaTypeDropDown.Value = 'Dipole';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Gain and Directivity Calculator';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.GEditFieldLabel = uilabel(app.UIFigure);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainLabel = uilabel(app.UIFigure);
            app.GainLabel.Text = 'Gain';
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DirectivityLabel = uilabel(app.UIFigure);
            app.DirectivityLabel.Text = 'Directivity';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyLabel = uilabel(app.UIFigure);
            app.EfficiencyLabel.Text = 'Efficiency';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end