classdef Project4_VSWR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        CalculateButton             matlab.ui.control.Button
        MLEditField                 matlab.ui.control.NumericEditField
        MLEditFieldLabel            matlab.ui.control.Label
        RLEditField                 matlab.ui.control.NumericEditField
        RLEditFieldLabel            matlab.ui.control.Label
        VSWREditField               matlab.ui.control.NumericEditField
        VSWREditFieldLabel          matlab.ui.control.Label
        RCEditField                 matlab.ui.control.NumericEditField
        RCEditFieldLabel            matlab.ui.control.Label
        MismatchLossLabel           matlab.ui.control.Label
        ReturnLossLabel             matlab.ui.control.Label
        VSWRLabel                   matlab.ui.control.Label
        ReflectionCoefficientLabel  matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            gamma=app.RCEditField.Value;
            if gamma<0||gamma>1
                uialert(app.UIFigure, ...
                    'Reflection Coefficient must be between 0 and 1' ,...
                    'invalid input');
                return;
            end

            VSWR=(1+gamma)/(1-gamma);
            RL=-20*log10(gamma);
            ML=-10*log10(1-gamma^2);
            app.VSWREditField.Value=VSWR;
            app.RLEditField.Value=RL;
            app.MLEditField.Value=ML;
            g=0.01:0.01:0.99;
            vswr=(1+g)./(1-g);
            rl=-20*log10(g);
            ml=-10*log10(1-g.^2);

            plot(app.UIAxes,g,vswr,'b','LineWidth',2);
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'Reflection Coefficient');
            ylabel(app.UIAxes,'VSWR');
            title(app.UIAxes,'VSWR vs RC');

            plot(app.UIAxes2,g,rl,'r','LineWidth',2);
            grid(app.UIAxes2,'on');
            xlabel(app.UIAxes2,'Reflection Coefficient');
            ylabel(app.UIAxes2,'Return Loss(dB)');
            title(app.UIAxes2,'RL vs RC');

            plot(app.UIAxes3,g,ml,'k','LineWidth',2);
            grid(app.UIAxes3,'on');
            xlabel(app.UIAxes3,'Reflection Coefficient');
            ylabel(app.UIAxes3,'Mismatch Loss(dB)');
            title(app.UIAxes3,'ML vs RC');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RCEditField.Value=0;
            app.VSWREditField.Value=0;
            app.RLEditField.Value=0;
            app.MLEditField.Value=0;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);

            title(app.UIAxes,'VSWR vs Reflection Coefficient');
            xlabel(app.UIAxes,'Reflection Coefficient');
            ylabel(app.UIAxes,'VSWR');

            title(app.UIAxes2,'Return Loss vs Reflection Coefficient');
            xlabel(app.UIAxes2,'Reflection Coefficient');
            ylabel(app.UIAxes2,'Return Loss(dB)');

            title(app.UIAxes3,'MisMatch Loss vs Reflection Coefficient');
            xlabel(app.UIAxes3,'Reflection Coefficient');
            ylabel(app.UIAxes3,'Mismatch Loss(dB)');
            
            grid(app.UIAxes,'on');
            grid(app.UIAxes2,'on');
            grid(app.UIAxes3,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'VSWR vs RC')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'RL vs RC')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'ML vs RC')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ReflectionCoefficientLabel = uilabel(app.UIFigure);
            app.ReflectionCoefficientLabel.Text = 'Reflection Coefficient';
            app.VSWRLabel = uilabel(app.UIFigure);
            app.VSWRLabel.Text = 'VSWR';
            app.ReturnLossLabel = uilabel(app.UIFigure);
            app.ReturnLossLabel.Text = 'Return Loss';
            app.MismatchLossLabel = uilabel(app.UIFigure);
            app.MismatchLossLabel.Text = 'Mismatch Loss';
            app.RCEditFieldLabel = uilabel(app.UIFigure);
            app.RCEditFieldLabel.Text = 'RC';
            app.RCEditField = uieditfield(app.UIFigure, 'numeric');
            app.VSWREditFieldLabel = uilabel(app.UIFigure);
            app.VSWREditFieldLabel.Text = 'VSWR';
            app.VSWREditField = uieditfield(app.UIFigure, 'numeric');
            app.RLEditFieldLabel = uilabel(app.UIFigure);
            app.RLEditFieldLabel.Text = 'RL';
            app.RLEditField = uieditfield(app.UIFigure, 'numeric');
            app.MLEditFieldLabel = uilabel(app.UIFigure);
            app.MLEditFieldLabel.Text = 'ML';
            app.MLEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'VSWR and Return Loss Analysis Tool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project4_VSWR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end